classdef xinhao < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        Tab                    matlab.ui.container.Tab
        Panel_4                matlab.ui.container.Panel
        Panel_6                matlab.ui.container.Panel
        DropDown_SpectrumType  matlab.ui.control.DropDown
        Label_9                matlab.ui.control.Label
        EditField_FFTLength    matlab.ui.control.NumericEditField
        FFTEditFieldLabel      matlab.ui.control.Label
        DropDown_Window        matlab.ui.control.DropDown
        Label_8                matlab.ui.control.Label
        Button_25              matlab.ui.control.Button
        FFTButton              matlab.ui.control.Button
        MATLABTextArea3        matlab.ui.control.TextArea
        TextArea_2Label        matlab.ui.control.Label
        Panel_5                matlab.ui.container.Panel
        Button_22              matlab.ui.control.Button
        Button_20              matlab.ui.control.Button
        Button_19              matlab.ui.control.Button
        RMSButton_2            matlab.ui.control.Button
        Button_18              matlab.ui.control.Button
        Button_17              matlab.ui.control.Button
        Button_16              matlab.ui.control.Button
        MATLABPanel_2          matlab.ui.container.Panel
        Button_23              matlab.ui.control.Button
        Button_21              matlab.ui.control.Button
        Button_15              matlab.ui.control.Button
        Button_14              matlab.ui.control.Button
        RMSButton              matlab.ui.control.Button
        Button_13              matlab.ui.control.Button
        Button_12              matlab.ui.control.Button
        Button_11              matlab.ui.control.Button
        MATLABTextArea_2       matlab.ui.control.TextArea
        Label_7                matlab.ui.control.Label
        UIAxes4                matlab.ui.control.UIAxes
        TextArea               matlab.ui.control.TextArea
        Panel_3                matlab.ui.container.Panel
        MATLABTextArea3_2      matlab.ui.control.TextArea
        TextArea_2Label_2      matlab.ui.control.Label
        Button_24              matlab.ui.control.Button
        DropDown_4             matlab.ui.control.DropDown
        Label_6                matlab.ui.control.Label
        DropDown_3             matlab.ui.control.DropDown
        Label_5                matlab.ui.control.Label
        DropDown_2             matlab.ui.control.DropDown
        Label_4                matlab.ui.control.Label
        Button_10              matlab.ui.control.Button
        UIAxes3                matlab.ui.control.UIAxes
        Panel                  matlab.ui.container.Panel
        Button_8               matlab.ui.control.Button
        EditField_2            matlab.ui.control.NumericEditField
        Label_3                matlab.ui.control.Label
        EditField              matlab.ui.control.NumericEditField
        Label_2                matlab.ui.control.Label
        Button_4               matlab.ui.control.Button
        DropDown               matlab.ui.control.DropDown
        Label                  matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
        Panel_2                matlab.ui.container.Panel
        Button_9               matlab.ui.control.Button
        Button_7               matlab.ui.control.Button
        Button_6               matlab.ui.control.Button
        Button_5               matlab.ui.control.Button
        UIAxes2                matlab.ui.control.UIAxes
        Tab2                   matlab.ui.container.Tab
    end

    
    properties (Access = private)
        recorder % Description
        %aduioData % Description
        signalData % 存储所有可用的信号
        selectedSignal1 % 第一个选中的信号
        selectedSignal2 % 第二个选中的信号 % Description
        OperationResult % 存储最后的运算结果
        LastOperationType % 存储最后的运算类型
        RecordSampleRate % 录音采样率
        FFTResult % 存储 FFT 计算结果
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 生成一些默认信号 (假设采样率为 fs)
            fs = 44100; 
            t = 0:1/fs:1; % 1s 时间向量
            app.RecordSampleRate =fs;
            app.signalData.Sine = sin(2*pi*10*t); % 10Hz 正弦波
            app.signalData.Square = square(2*pi*10*t); % 10Hz 方波
            app.signalData.Chirp = chirp(t, 0, 1, 100); % 线性 Chirp 信号
            app.signalData.Step = double(t > 0.5); % 阶跃信号
            app.signalData.Recorded = []; % 录音数据，后面填充
            app.selectedSignal1 = app.signalData.Sine;
            app.selectedSignal2 = app.signalData.Sine;
            app.DropDown_Window.Value = '汉明窗'; 
            app.EditField_FFTLength.Value = 1024;
            app.DropDown_SpectrumType.Value = '幅度谱'; % 默认选择幅度谱
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            fs = 1000 ;
            t = 0:1/fs:2;
            freq = app.EditField.Value;
            amp = app.EditField_2.Value;
            % choose
            switch app.DropDown.Value
                case '正弦信号'
                    y = amp * sin(2 * pi * freq * t);
                    % 绘制信号
                    plot(app.UIAxes, t, y);
                    title(app.UIAxes, '正弦信号');
                    xlabel(app.UIAxes, '时间 (s)');
                    ylabel(app.UIAxes, '幅度');
                case '方波信号'
                    y = amp * square(2 * pi * freq * t);
                    plot(app.UIAxes, t, y);
                    title(app.UIAxes, '方波信号');
                    xlabel(app.UIAxes, '时间 (s)');
                    ylabel(app.UIAxes, '幅度');
                case '阶跃信号'
                    y = amp * (t >= 0.5);
                    plot(app.UIAxes, t, y);
                    title(app.UIAxes, '阶跃信号');
                    xlabel(app.UIAxes, '时间 (s)');
                    ylabel(app.UIAxes, '幅度');
                case 'chirp信号'
                    y = amp * chirp(t, 0, 1, freq);
                    plot(app.UIAxes, t, y);
                    title(app.UIAxes, 'chirp信号');
                    xlabel(app.UIAxes, '时间 (s)');
                    ylabel(app.UIAxes, '幅度');
            end
            
        end

        % Callback function
        function Button_8Pushed(app, event)
            %clear
            cla(app.UIAxes);
            title(app.UIAxes,'信号已清除');
            xlabel(app.UIAxes, '时间 (s)');
            ylabel(app.UIAxes, '幅度');
        end

        % Button pushed function: Button_8
        function clear(app, event)
            %clear
            cla(app.UIAxes);
            title(app.UIAxes,'信号已清除');
            xlabel(app.UIAxes, '时间 (s)');
            ylabel(app.UIAxes, '幅度');   
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            % fs = 44100; % 采样率
            % app.recorder = audiorecorder(fs, 16, 2); % 立体声 16 位
            % record(app.recorder); % 开始录音
            % 
            % % 实时更新波形
            % while isrecording(app.recorder)
            %     audioData = getaudiodata(app.recorder);
            %     plot(app.UIAxes2, audioData);
            %     title(app.UIAxes2, '实时录音信号');
            %     xlabel(app.UIAxes2, '采样点');
            %     ylabel(app.UIAxes2, '幅度');
            %     pause(0.1); % UI 刷新
            % end
            fs = 44100; % 采样率
            app.recorder = audiorecorder(fs, 16, 2); % 立体声 16 位
            record(app.recorder); % 开始录音
            disp('录音开始...');
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            % stop(app.recorder); % 停止录音
            % audioData = getaudiodata(app.recorder);
            % 
            % % 录音结束后，波形固定
            % plot(app.UIAxes2, audioData);
            % title(app.UIAxes2, '最终录音信号');
            if ~isempty(app.recorder)
        stop(app.recorder); % 停止录音
        disp('录音结束。');

        % 获取录音数据
        audioData = getaudiodata(app.recorder);
        app.signalData.Recorded = audioData; % 存储录音数据

        % 生成时间轴
        fs = 44100; % 采样率
        N = size(audioData, 1); % 录音数据的长度
        t = (0:N-1) / fs; % 时间轴

        % 在 UIAxes2 上绘制录音波形
        plot(app.UIAxes2, t, audioData);
        title(app.UIAxes2, '录音信号');
        xlabel(app.UIAxes2, '时间 (s)');
        ylabel(app.UIAxes2, '幅度');
        legend(app.UIAxes2, '左声道', '右声道'); % 添加图例
        grid(app.UIAxes2, 'on'); % 添加网格
    else
        disp('请先开始录音！');
    end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
               if ~isempty(app.recorder)
                    audioData = getaudiodata(app.recorder);
                    gain = 5;
                    audioData = audioData   *  gain;
                    sound(audioData, app.recorder.SampleRate);
                    disp('正在播放录音...');
               else
                    disp('没有可播放的录音，请先录音！');
               end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            %app.audioData = []; % 清空存储的录音    
            cla(app.UIAxes2);
            title(app.UIAxes2,'信号已清除');
            xlabel(app.UIAxes2, '时间 (s)');
            ylabel(app.UIAxes2, '幅度');   
        end

        % Callback function
        function DropDown_3Clicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)

            % 获取用户选择的信号类型
            selectedSignal = app.DropDown_3.Value;
            
            % 显示选择的信号
            disp(['信号1 选择了: ', selectedSignal]);
            
            % 赋值到 app 变量，便于后续计算
            switch selectedSignal
                case '正弦信号'
                    app.selectedSignal1 = app.signalData.Sine;
                case '方波信号'
                    app.selectedSignal1 = app.signalData.Square;
                case 'Chirp信号'
                    app.selectedSignal1 = app.signalData.Chirp;
                case '阶跃信号'
                    app.selectedSignal1 = app.signalData.Step;
                case '采集的麦克风信号'
                    app.selectedSignal1 = app.signalData.Recorded;
                otherwise
                    app.selectedSignal1 = [];
            end
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
             % 获取用户选择的信号类型
            selectedSignal = app.DropDown_4.Value;
            
            % 显示选择的信号
            disp(['信号2 选择了: ', selectedSignal]);
            
            % 赋值到 app 变量，便于后续计算
            switch selectedSignal
                case '正弦信号'
                    app.selectedSignal2 = app.signalData.Sine;
                case '方波信号'
                    app.selectedSignal2 = app.signalData.Square;
                case 'Chirp信号'
                    app.selectedSignal2 = app.signalData.Chirp;
                case '阶跃信号'
                    app.selectedSignal2 = app.signalData.Step;
                case '采集的麦克风信号'
                    app.selectedSignal2 = app.signalData.Recorded;
                otherwise
                    app.selectedSignal2 = [];
            end
            
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
           if isempty(app.selectedSignal1) || isempty(app.selectedSignal2)
    uialert(app.UIFigure, '请先选择两个信号', '错误');
    return;
end

operation = app.DropDown_2.Value;

% 对于加法和减法，检查信号长度是否一致
if strcmp(operation, '加法') || strcmp(operation, '减法')
    len1 = length(app.selectedSignal1);
    len2 = length(app.selectedSignal2);
    if len1 ~= len2
        uialert(app.UIFigure, '两个信号的长度不匹配，无法执行加法或减法运算', '错误');
        return;
    end
end

try
    switch operation
        case '加法'
            result = app.selectedSignal1 + app.selectedSignal2;
            t = (0:length(result)-1) / app.RecordSampleRate; % 生成时间轴
            
        case '减法'
            result = app.selectedSignal1 - app.selectedSignal2;
            t = (0:length(result)-1) / app.RecordSampleRate; % 生成时间轴
            
        case '卷积'
            % 如果信号不是向量，则取第一个通道
            s1 = app.selectedSignal1;
            s2 = app.selectedSignal2;
            if ~isvector(s1)
                s1 = s1(:,1);  % 选择第一个通道
            end
            if ~isvector(s2)
                s2 = s2(:,1);  % 选择第一个通道
            end
            result = conv(s1, s2);
            t = (0:length(result)-1) / app.RecordSampleRate; % 生成时间轴
            
        case '相关性'
            % 如果信号不是向量，则取第一个通道
            s1 = app.selectedSignal1;
            s2 = app.selectedSignal2;
            if ~isvector(s1)
                s1 = s1(:,1);  % 选择第一个通道
            end
            if ~isvector(s2)
                s2 = s2(:,1);  % 选择第一个通道
            end
            result = xcorr(s1, s2);
            lags = (-length(result)/2:(length(result)/2-1)) / app.RecordSampleRate; % 生成延迟轴
            t = lags; % 使用延迟轴作为时间轴
            
        otherwise
            uialert(app.UIFigure, '未知运算类型', '错误');
            return;
    end
    
    % 存储结果和操作类型
    app.OperationResult = result;
    app.LastOperationType = operation;
    
    % 绘制结果
    plot(app.UIAxes3, t, result, 'LineWidth', 1.5);
    title(app.UIAxes3, ['运算结果: ' operation]);
    xlabel(app.UIAxes3, '时间 (s)');
    ylabel(app.UIAxes3, '幅度');
    grid(app.UIAxes3, 'on'); % 添加网格
    legend(app.UIAxes3, '运算结果'); % 添加图例
    
catch ME
    uialert(app.UIFigure, ME.message, '运算错误');
end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
           app.MATLABTextArea_2.Value = {''}; % 先清空文本框
            % 检查录音数据是否存在
            if isempty(app.signalData.Recorded)
                uialert(app.UIFigure, '录音数据为空，请先进行录音！', '错误');
                return;
            end
        
            % 获取录音数据，若为立体声则转换为单通道（取均值）
            audioData = app.signalData.Recorded;
            if size(audioData,2) > 1
                audioData = mean(audioData,2);
            end
        
            % 定义采样率，需与录音时一致（例如44100 Hz）
            fs = 44100;
            
            % 生成时间向量
            t = (0:length(audioData)-1)/fs;
            
            % 时域分析
            peakAmplitude = max(abs(audioData));     % 峰值幅度
            rmsValue = rms(audioData);                 % 均方根值
            zeroCrossings = sum(abs(diff(audioData > 0)));  % 过零率
            
            % 频域分析（计算 FFT）
            N = length(audioData);
            Y = fft(audioData);
            f = (0:N-1)*(fs/N);
            % 保留正频率部分
            if mod(N,2)==0
                Y = Y(1:N/2+1);
                f = f(1:N/2+1);
            else
                Y = Y(1:ceil(N/2));
                f = f(1:ceil(N/2));
            end
            mag = abs(Y);
            
            % 计算频域额外参数
            % 谱重心（频谱质心）
            spectralCentroid = sum(f .* mag) / sum(mag);
            % 谱带宽（频谱标准差）
            spectralBandwidth = sqrt(sum(((f - spectralCentroid).^2).*mag) / sum(mag));
            % 频谱峰值：幅值最大的频率
            [~, idxPeak] = max(mag);
            peakFrequency = f(idxPeak);
            
            % 将所有分析结果显示在 TextArea_2 上
            analysisStr = sprintf(['时域分析结果:\n' ...
                                   '峰值幅度: %.4f\nRMS值: %.4f\n过零率: %d\n\n' ...
                                   '频域分析结果:\n' ...
                                   '谱峰频率: %.2f Hz\n谱重心: %.2f Hz\n谱带宽: %.2f Hz'], ...
                                   peakAmplitude, rmsValue, zeroCrossings, peakFrequency, spectralCentroid, spectralBandwidth);
            app.MATLABTextArea_2.Value = {analysisStr};
            
            % 绘制时域图（显示在 UIAxes3 上）
            plot(app.UIAxes3, t, audioData);
            xlabel(app.UIAxes3, '时间 (秒)');
            ylabel(app.UIAxes3, '幅度');
            title(app.UIAxes3, '录音信号时域分析');
            
                % 绘制频域图在 UIAxes_Freq 中
                plot(app.UIAxes, f, abs(Y));
                xlabel(app.UIAxes, '频率 (Hz)');
                ylabel(app.UIAxes, '幅度');
                title(app.UIAxes, '录音信号频域分析');
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            % 计算均方值的回调
            app.MATLABTextArea_2.Value = {''}; % 清空文本框
            if isempty(app.signalData.Recorded)
                uialert(app.UIFigure,'录音数据为空','错误');
                return;
            end
            % 获取录音数据，若为立体声则转换为单通道（取均值）
            audioData = app.signalData.Recorded;
            if size(audioData,2) > 1
                audioData = mean(audioData,2);
            end
            % 采样率（需与录音一致）
            fs = 44100; 
            % 生成时间向量（保持代码格式统一）
            t = (0:length(audioData)-1)/fs;
            
            % 核心计算：均方值 = 信号平方的均值
            meanSquareValue = mean(audioData.^2); 
            analysis_ms = sprintf('均方值：%.4f', meanSquareValue);
            app.MATLABTextArea_2.Value = {analysis_ms};
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            %计算周期的回调
            app.MATLABTextArea_2.Value = {''}; %清空文本框
            if isempty(app.signalData.Recorded)
                uialert(app.UIFigure,'录音数据为空','错误');
                return;
            end
            % 获取录音数据，若为立体声则转换为单通道（取均值）
            audioData = app.signalData.Recorded;
            if size(audioData,2) > 1
                audioData = mean(audioData,2);
            end
                % 采样率（需与录音一致）
              fs = 44100; 

               % 生成时间向量
             t = (0:length(audioData)-1)/fs;

            [~,locs] = findpeaks(audioData,'MinPeakHeight',mean(audioData));
            if length(locs) > 1
                T = mean(diff(locs)) /fs;
            else
                T = NaN;
            end
            analysis_t = sprintf    ('周期：%.4f',T);
            app.MATLABTextArea_2.Value = {analysis_t};
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            %计算方差
            app.MATLABTextArea_2.Value = {''}; %清空文本框
            if isempty(app.signalData.Recorded)
                uialert(app.UIFigure,'录音数据为空','错误');
                return;
            end
            % 获取录音数据，若为立体声则转换为单通道（取均值）
            audioData = app.signalData.Recorded;
            if size(audioData,2) > 1
                audioData = mean(audioData,2);
            end
                % 采样率（需与录音一致）
              fs = 44100; 

               % 生成时间向量
             t = (0:length(audioData)-1)/fs;

             varianceValue = var(audioData);       % 计算方差
             analysis_var = sprintf ('方差：%.4f',varianceValue);
             app.MATLABTextArea_2.Value = {analysis_var};
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            %计算均值、
            app.MATLABTextArea_2.Value = {''}; %清空文本框
            if isempty(app.signalData.Recorded)
                uialert(app.UIFigure,'录音数据为空','错误');
                return;
            end
            % 获取录音数据，若为立体声则转换为单通道（取均值）
            audioData = app.signalData.Recorded;
            if size(audioData,2) > 1
                audioData = mean(audioData,2);
            end
                % 采样率（需与录音一致）
              fs = 44100; 

               % 生成时间向量
             t = (0:length(audioData)-1)/fs;
             meanValue = mean(audioData);
             analysis_m = sprintf('均值: %.4f',meanValue);
             app.MATLABTextArea_2.Value = {analysis_m};
        end

        % Button pushed function: Button_21
        function Button_21Pushed(app, event)
            % 计算峰值的回调
            app.MATLABTextArea_2.Value = {''}; %清空文本框
            if isempty(app.signalData.Recorded)
                uialert(app.UIFigure,'录音数据为空','错误');
                return;
            end
            % 获取录音数据，若为立体声则转换为单通道（取均值）
            audioData = app.signalData.Recorded;
            if size(audioData,2) > 1
                audioData = mean(audioData,2);
            end
            % 采样率（需与录音一致）
            fs = 44100; 
            % 生成时间向量（虽然未使用但保持代码格式统一）
            t = (0:length(audioData)-1)/fs;
            
            % 计算峰值（取信号的最大绝对值）
            peakValue = max(abs(audioData)); 
            analysis_peak = sprintf('峰值：%.4f', peakValue);
            app.MATLABTextArea_2.Value = {analysis_peak};
        end

        % Button pushed function: RMSButton
        function RMSButtonPushed(app, event)
            % 计算均方根值（RMS）的回调
            app.MATLABTextArea_2.Value = {''}; %清空文本框
            if isempty(app.signalData.Recorded)
                uialert(app.UIFigure,'录音数据为空','错误');
                return;
            end
            % 获取录音数据，若为立体声则转换为单通道（取均值）
            audioData = app.signalData.Recorded;
            if size(audioData,2) > 1
                audioData = mean(audioData,2);
            end
            % 采样率（需与录音一致）
            fs = 44100; 
            % 生成时间向量（虽然未使用但保持代码格式统一）
            t = (0:length(audioData)-1)/fs;
            
            % 计算均方根值（先平方→均值→开根号）
            rmsValue = sqrt(mean(audioData.^2)); 
            analysis_rms = sprintf('均方根值：%.4f', rmsValue);
            app.MATLABTextArea_2.Value = {analysis_rms};
        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
            %计算峰值
            app.MATLABTextArea3.Value = {''};
            if isempty(app.signalData.Recorded)
                uialert(app.UIFigure,'录音数据为空','错误');
                return;
            end
            audioData = mean(app.signalData.Recorded, 2);
            fs = 44100;
            
            % 手动计算峰值（绝对值最大值）
            peakValue = 0;
            for i = 1:length(audioData)
                currentAbs = abs(audioData(i));
                if currentAbs > peakValue
                    peakValue = currentAbs;
                end
            end
            
            analysis_peak = sprintf('峰值：%.4f', peakValue);
            app.MATLABTextArea3.Value = {analysis_peak};
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            %计算均值
            app.MATLABTextArea3.Value = {''};
            if isempty(app.signalData.Recorded)
                uialert(app.UIFigure,'录音数据为空','错误');
                return;
            end
            audioData = mean(app.signalData.Recorded, 2);
            fs = 44100;
            
            % 手动均值计算
            sumVal = 0;
            for i = 1:length(audioData)
                sumVal = sumVal + audioData(i);
            end
            meanValue = sumVal / length(audioData);
            
            analysis_m = sprintf('均值：%.4f', meanValue);
            app.MATLABTextArea3.Value = {analysis_m};
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            %计算方差
            app.MATLABTextArea3.Value = {''};
            if isempty(app.signalData.Recorded)
                uialert(app.UIFigure,'录音数据为空','错误');
                return;
            end
            audioData = mean(app.signalData.Recorded, 2);
            fs = 44100;
            
            % 手动计算均值
            sumVal = 0;
            for i = 1:length(audioData)
                sumVal = sumVal + audioData(i);
            end
            meanValue = sumVal / length(audioData);
            
            % 手动计算方差（总体方差）
            sumSqDiff = 0;
            for i = 1:length(audioData)
                sumSqDiff = sumSqDiff + (audioData(i) - meanValue)^2;
            end
            varianceValue = sumSqDiff / length(audioData);
            
            analysis_var = sprintf('方差：%.4f', varianceValue);
            app.MATLABTextArea3.Value = {analysis_var};
        end

        % Button pushed function: Button_22
        function Button_22Pushed(app, event)
            %计算均方值
            app.MATLABTextArea3.Value = {''};
            if isempty(app.signalData.Recorded)
                uialert(app.UIFigure,'录音数据为空','错误');
                return;
            end
            audioData = mean(app.signalData.Recorded, 2);
            fs = 44100;
            
            % 手动计算均方值
            sumSq = 0;
            for i = 1:length(audioData)
                sumSq = sumSq + audioData(i)^2;
            end
            meanSquareValue = sumSq / length(audioData);
            
            analysis_ms = sprintf('均方值：%.4f', meanSquareValue);
            app.MATLABTextArea3.Value = {analysis_ms};
        end

        % Button pushed function: RMSButton_2
        function RMSButton_2Pushed(app, event)
            %rms'
            app.MATLABTextArea3.Value = {''};
            if isempty(app.signalData.Recorded)
                uialert(app.UIFigure,'录音数据为空','错误');
                return;
            end
            audioData = mean(app.signalData.Recorded, 2);
            fs = 44100;
            
            % 手动计算 RMS
            sumSq = 0;
            for i = 1:length(audioData)
                sumSq = sumSq + audioData(i)^2;
            end
            rmsValue = sqrt(sumSq / length(audioData));
            
            analysis_rms = sprintf('RMS：%.4f', rmsValue);
            app.MATLABTextArea3.Value = {analysis_rms};
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            %周期
            app.MATLABTextArea3.Value = {''}; % 输出到 Area3
            if isempty(app.signalData.Recorded)
                uialert(app.UIFigure,'录音数据为空','错误');
                return;
            end
            audioData = mean(app.signalData.Recorded, 2); % 立体声转单声道
            fs = 44100;
            
            % 手动峰值检测算法
            threshold = mean(audioData); % 阈值设为均值（与原代码逻辑一致）
            minPeakDistance = fs * 0.1; % 最小峰间距（0.1秒，可调整）
            locs = [];
            for i = 2:length(audioData)-1
                if audioData(i) > threshold && ...             % 高于阈值
                   audioData(i) > audioData(i-1) && ...        % 局部极大值
                   audioData(i) > audioData(i+1) && ...        % 局部极大值
                   (isempty(locs) || (i - locs(end)) > minPeakDistance) % 间距限制
                    locs = [locs; i];
                end
            end
            
            % 计算周期
            if length(locs) > 1
                T = mean(diff(locs)) / fs;
            else
                T = NaN;
            end
            analysis_t = sprintf('周期：%.4f', T);
            app.MATLABTextArea3.Value = {analysis_t};
        end

        % Button pushed function: Button_23
        function Button_23Pushed(app, event)
            %集成
            app.MATLABTextArea_2.Value = {''}; % 清空 Area2
            if isempty(app.signalData.Recorded)
                uialert(app.UIFigure,'录音数据为空','错误');
                return;
            end
            audioData = mean(app.signalData.Recorded, 2);
            fs = 44100;
            
            % ========== 库函数计算 ==========
            % 均值
            meanValue = mean(audioData);
            
            % 方差（使用总体方差公式）
            varianceValue = var(audioData, 1);
            
            % 均方值
            meanSquareValue = mean(audioData.^2);
            
            % RMS
            rmsValue = rms(audioData);
            
            % 峰值
            peakValue = max(abs(audioData));
            
            % 周期检测（使用 findpeaks）
            [~, locs] = findpeaks(audioData,...
                'MinPeakHeight', mean(audioData),...
                'MinPeakDistance', 0.1*fs); % 最小间隔0.1秒
            if length(locs) > 1
                T = mean(diff(locs)) / fs;
            else
                T = NaN;
            end
            
            % ========== 结果格式化 ==========
            resultText = {
                sprintf('==== 库函数计算结果 ===='),...
                sprintf('周期：%.4f s', T),...
                sprintf('均值：%.4f', meanValue),...
                sprintf('方差：%.4f', varianceValue),...
                sprintf('均方值：%.4f', meanSquareValue),...
                sprintf('RMS：%.4f', rmsValue),...
                sprintf('峰值：%.4f', peakValue)
            };
            app.MATLABTextArea_2.Value = resultText;
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            %集成手动
            app.MATLABTextArea3.Value = {''}; % 清空 Area3
            if isempty(app.signalData.Recorded)
                uialert(app.UIFigure,'录音数据为空','错误');
                return;
            end
            audioData = mean(app.signalData.Recorded, 2); % 立体声转单声道
            fs = 44100;
            N = length(audioData);
            
            % ========== 手动计算所有指标 ==========
            % 初始化累加变量
            sumVal = 0;      % 均值累加
            sumSq = 0;       % 均方值累加
            sumSqDiff = 0;   % 方差累加
            peakValue = 0;   % 峰值
            locs = [];       % 峰值位置
            
            % 单次遍历完成多项计算
            for i = 1:N
                % 均值计算
                sumVal = sumVal + audioData(i);
                
                % 均方值计算
                sumSq = sumSq + audioData(i)^2;
                
                % 峰值计算
                currentAbs = abs(audioData(i));
                if currentAbs > peakValue
                    peakValue = currentAbs;
                end
                
                % 周期检测（带阈值的局部极大值）
                if i > 1 && i < N
                    if audioData(i) > mean(audioData) && ...       % 阈值条件
                       audioData(i) > audioData(i-1) && ...
                       audioData(i) > audioData(i+1)
                        locs = [locs; i];
                    end
                end
            end
            
            % 后处理计算
            meanValue = sumVal / N;                % 均值
            meanSquareValue = sumSq / N;           % 均方值
            rmsValue = sqrt(meanSquareValue);      % RMS
            
            % 方差计算需要二次遍历
            for i = 1:N
                sumSqDiff = sumSqDiff + (audioData(i) - meanValue)^2;
            end
            varianceValue = sumSqDiff / N;
            
            % 周期计算
            if length(locs) > 1
                T = mean(diff(locs)) / fs;
            else
                T = NaN;
            end
            
            % ========== 结果格式化 ==========
            resultText = {
                sprintf('==== 手动计算结果 ===='),...
                sprintf('周期：%.4f s', T),...
                sprintf('均值：%.4f', meanValue),...
                sprintf('方差：%.4f', varianceValue),...
                sprintf('均方值：%.4f', meanSquareValue),...
                sprintf('RMS：%.4f', rmsValue),...
                sprintf('峰值：%.4f', peakValue)
            };
            app.MATLABTextArea3.Value = resultText;
        end

        % Button pushed function: Button_24
        function Button_24Pushed(app, event)
        % 校验数据
if isempty(app.OperationResult)
    uialert(app.UIFigure, '请先执行运算操作', '错误');
    return;
end

% 获取运算结果并确保为单通道
analysisData = app.OperationResult; % 获取运算结果
if ~isvector(analysisData) % 如果是矩阵（多通道）
    analysisData = mean(analysisData, 2); % 取均值转换为单通道
end
signalLength = length(analysisData); % 样本点数

% 检查 fs 是否为标量
fs = 44100; % 采样率
if ~isscalar(fs)
    uialert(app.UIFigure, '采样率必须为标量值', '错误');
    return;
end

% 计算信号时长
duration = signalLength / fs; % 信号时长

% ========== 通用特征计算 ==========
% 手动计算均值
sumVal = 0;
for i = 1:signalLength
    sumVal = sumVal + analysisData(i);
end
meanValue = sumVal / signalLength;

% 手动计算方差（总体方差）
sumSqDiff = 0;
for i = 1:signalLength
    sumSqDiff = sumSqDiff + (analysisData(i) - meanValue)^2;
end
varianceValue = sumSqDiff / signalLength;

% 手动计算均方值和RMS
sumSq = 0;
for i = 1:signalLength
    sumSq = sumSq + analysisData(i)^2;
end
meanSquareValue = sumSq / signalLength;
rmsValue = sqrt(meanSquareValue);

% 手动计算峰值
peakValue = 0;
for i = 1:signalLength
    currentAbs = abs(analysisData(i));
    if currentAbs > peakValue
        peakValue = currentAbs;
    end
end

% ========== 操作特异性分析 ==========
try
    switch app.LastOperationType
        case '卷积'
            % 获取原始信号参数
            s1 = app.selectedSignal1;
            s2 = app.selectedSignal2;
            s1Len = length(s1);
            s2Len = length(s2);
            
            % 理论结果长度校验
            expectedLength = s1Len + s2Len - 1;
            
            % 峰值时间定位
            [~, maxIdx] = max(abs(analysisData));
            peakTime = (maxIdx-1)/fs;
            
            % 能量计算（物理单位需校准）
            energy = sum(analysisData.^2) / fs;
            
            specAnalysis = {
                sprintf('理论结果长度：%d', expectedLength), 
                sprintf('实际结果长度：%d', signalLength),
                sprintf('最大幅值位置：%.3f s', peakTime),
                sprintf('系统总能量：%.4e J', energy)
            };
            
        case '相关性'
            % 零滞后点计算
            zeroLagPos = floor(signalLength/2) + 1;
            
            % 延迟计算
            [~, maxIdx] = max(analysisData);
            delaySamples = maxIdx - zeroLagPos;
            delayTime = delaySamples / fs;
            
            % 正确归一化计算
            s1 = app.selectedSignal1;
            s2 = app.selectedSignal2;
            normFactor = sqrt(sum(s1.^2)) * sqrt(sum(s2.^2));
            normalizedCorr = analysisData ./ normFactor; % 使用逐元素除法
            maxNormCorr = max(normalizedCorr);
            
            specAnalysis = {
                sprintf('最大相关系数：%.4f', max(normalizedCorr)), 
                sprintf('归一化最大值：%.4f', maxNormCorr),
                sprintf('估计延迟：%.4f s', delayTime),
                sprintf('零滞后位置：%d 点', zeroLagPos)
            };
            
        otherwise
            specAnalysis = {'当前操作无专用分析指标'};
    end
    
    % ========== 结果格式化输出 ==========
    commonMetrics = {
        sprintf('信号时长：%.3f s', duration), 
        sprintf('均值：%.4f', meanValue),
        sprintf('方差：%.4f', varianceValue),
        sprintf('均方值：%.4f', meanSquareValue),
        sprintf('RMS：%.4f', rmsValue),
        sprintf('峰值：%.4f', peakValue)
    };
    
    fullAnalysis = [specAnalysis; commonMetrics];
    app.MATLABTextArea3_2.Value = fullAnalysis;
    
catch ME
    uialert(app.UIFigure, ME.message, '分析错误');
    app.MATLABTextArea3_2.Value = {'分析过程中出现错误'};
end

        end

        % Button pushed function: Button_25
        function Button_25Pushed(app, event)
            % 检查是否已计算 FFT
    if isempty(app.FFTResult)
        uialert(app.UIFigure, '请先计算 FFT', '错误');
        return;
    end

    % 获取 FFT 结果
    Y = app.FFTResult.Y;
    f = app.FFTResult.f;
    N = app.FFTResult.N;

    % 获取频谱类型
    spectrumType = app.DropDown_SpectrumType.Value;

    % 绘制频谱
    switch spectrumType
        case '幅度谱'
            magnitude = abs(Y(1:N/2)); % 取正频率部分
            plot(app.UIAxes4, f(1:N/2), magnitude);
            title(app.UIAxes4, '幅度谱');
            ylabel(app.UIAxes4, '幅度');
        case '功率谱'
            power = abs(Y(1:N/2)).^2 / N; % 计算功率谱
            plot(app.UIAxes4, f(1:N/2), power);
            title(app.UIAxes4, '功率谱');
            ylabel(app.UIAxes4, '功率');
    end

    xlabel(app.UIAxes4, '频率 (Hz)');
    grid(app.UIAxes4, 'on');
        end

        % Button pushed function: FFTButton
        function FFTButtonPushed(app, event)
            % 获取当前信号
    if isempty(app.signalData.Recorded)
        uialert(app.UIFigure, '请先采集或生成信号', '错误');
        return;
    end
    signal = 100*app.signalData.Recorded; % 使用录音信号
    if size(signal, 2) > 1
        signal = mean(signal, 2); % 如果是立体声，转换为单声道
    end

    % 获取 FFT 长度
    N = app.EditField_FFTLength.Value;
    if N > length(signal)
        N = length(signal); % 如果 FFT 长度大于信号长度，则使用信号长度
    end

    % 获取窗函数
    windowType = app.DropDown_Window.Value;
    switch windowType
        case '矩形窗'
            window = rectwin(N);
        case '汉明窗'
            window = hamming(N);
        case '汉宁窗'
            window = hann(N);
    end

    % 应用窗函数
    signalWindowed = signal(1:N) .* window;

    % 计算 FFT
    Y = fft(signalWindowed, N);
    f = (0:N-1) * (app.RecordSampleRate / N); % 频率轴

    % 存储 FFT 结果
    app.FFTResult.Y = Y;
    app.FFTResult.f = f;
    app.FFTResult.N = N;

    disp('FFT 计算完成。');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = '语言信号采集';
            app.UIAxes2 = uiaxes(app.Panel_2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Button_5 = uibutton(app.Panel_2, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '开始采集';
            app.Button_6 = uibutton(app.Panel_2, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '结束采集';
            app.Button_7 = uibutton(app.Panel_2, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '播放音频';
            app.Button_9 = uibutton(app.Panel_2, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '清除录音';
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = '典型信号演示';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.Panel);
            app.Label.Text = '选择生成的信号类型';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'正弦信号', '方波信号', 'chirp信号', '阶跃信号'};
            app.DropDown.Value = '正弦信号';
            app.Button_4 = uibutton(app.Panel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '生成信号';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '频率';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField.Value = 10;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '幅度';
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.EditField_2.Value = 2;
            app.Button_8 = uibutton(app.Panel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @clear, true);
            app.Button_8.Text = '清除信号';
            app.Panel_3 = uipanel(app.Tab);
            app.Panel_3.Title = '信号运算';
            app.UIAxes3 = uiaxes(app.Panel_3);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.Button_10 = uibutton(app.Panel_3, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '执行运算';
            app.Label_4 = uilabel(app.Panel_3);
            app.Label_4.Text = '选择运算类型';
            app.DropDown_2 = uidropdown(app.Panel_3);
            app.DropDown_2.Items = {'加法', '减法', '卷积', '相关性', '', ''};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '加法';
            app.Label_5 = uilabel(app.Panel_3);
            app.Label_5.Text = '信号1';
            app.DropDown_3 = uidropdown(app.Panel_3);
            app.DropDown_3.Items = {'正弦信号', '方波信号', 'Chirp信号', '阶跃信号', '采集的麦克风信号'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = '正弦信号';
            app.Label_6 = uilabel(app.Panel_3);
            app.Label_6.Text = '信号2';
            app.DropDown_4 = uidropdown(app.Panel_3);
            app.DropDown_4.Items = {'正弦信号', '方波信号', 'chirp信号', '阶跃信号', '采集的麦克风信号'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Value = '正弦信号';
            app.Button_24 = uibutton(app.Panel_3, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @Button_24Pushed, true);
            app.Button_24.Text = '卷积/相关性分析';
            app.TextArea_2Label_2 = uilabel(app.Panel_3);
            app.TextArea_2Label_2.Text = '卷积/相关性分析结果';
            app.MATLABTextArea3_2 = uitextarea(app.Panel_3);
            app.TextArea = uitextarea(app.Tab);
            app.TextArea.Value = {'Proury''s MATLAB信号分析仪'};
            app.Panel_4 = uipanel(app.Tab);
            app.Panel_4.Title = '计算分析部分';
            app.UIAxes4 = uiaxes(app.Panel_4);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.Label_7 = uilabel(app.Panel_4);
            app.Label_7.Text = 'MATLAB算法结果显示';
            app.MATLABTextArea_2 = uitextarea(app.Panel_4);
            app.MATLABPanel_2 = uipanel(app.Panel_4);
            app.MATLABPanel_2.Title = 'MATLAB内置函数';
            app.Button_11 = uibutton(app.MATLABPanel_2, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '时域分析';
            app.Button_12 = uibutton(app.MATLABPanel_2, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '计算周期';
            app.Button_13 = uibutton(app.MATLABPanel_2, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '计算均值';
            app.RMSButton = uibutton(app.MATLABPanel_2, 'push');
            app.RMSButton.ButtonPushedFcn = createCallbackFcn(app, @RMSButtonPushed, true);
            app.RMSButton.Text = '计算RMS';
            app.Button_14 = uibutton(app.MATLABPanel_2, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '计算方差';
            app.Button_15 = uibutton(app.MATLABPanel_2, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '计算均方值';
            app.Button_21 = uibutton(app.MATLABPanel_2, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @Button_21Pushed, true);
            app.Button_21.Text = '计算峰值';
            app.Button_23 = uibutton(app.MATLABPanel_2, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @Button_23Pushed, true);
            app.Button_23.Text = '一键计算';
            app.Panel_5 = uipanel(app.Panel_4);
            app.Panel_5.Title = '手搓算法';
            app.Button_16 = uibutton(app.Panel_5, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '一键计算';
            app.Button_17 = uibutton(app.Panel_5, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '计算周期';
            app.Button_18 = uibutton(app.Panel_5, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '计算均值';
            app.RMSButton_2 = uibutton(app.Panel_5, 'push');
            app.RMSButton_2.ButtonPushedFcn = createCallbackFcn(app, @RMSButton_2Pushed, true);
            app.RMSButton_2.Text = '计算RMS';
            app.Button_19 = uibutton(app.Panel_5, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '计算方差';
            app.Button_20 = uibutton(app.Panel_5, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Text = '计算均值';
            app.Button_22 = uibutton(app.Panel_5, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @Button_22Pushed, true);
            app.Button_22.Text = '计算均方值';
            app.TextArea_2Label = uilabel(app.Panel_4);
            app.TextArea_2Label.Text = '手搓算法结果显示';
            app.MATLABTextArea3 = uitextarea(app.Panel_4);
            app.Panel_6 = uipanel(app.Panel_4);
            app.Panel_6.Title = '频域分析';
            app.FFTButton = uibutton(app.Panel_6, 'push');
            app.FFTButton.ButtonPushedFcn = createCallbackFcn(app, @FFTButtonPushed, true);
            app.FFTButton.Text = '计算FFT';
            app.Button_25 = uibutton(app.Panel_6, 'push');
            app.Button_25.ButtonPushedFcn = createCallbackFcn(app, @Button_25Pushed, true);
            app.Button_25.Text = '显示频谱';
            app.Label_8 = uilabel(app.Panel_6);
            app.Label_8.Text = '窗函数';
            app.DropDown_Window = uidropdown(app.Panel_6);
            app.DropDown_Window.Items = {'矩形窗', '汉明窗', '汉宁窗'};
            app.DropDown_Window.Value = '矩形窗';
            app.FFTEditFieldLabel = uilabel(app.Panel_6);
            app.FFTEditFieldLabel.Text = 'FFT长度输入';
            app.EditField_FFTLength = uieditfield(app.Panel_6, 'numeric');
            app.Label_9 = uilabel(app.Panel_6);
            app.Label_9.Text = '频谱类型';
            app.DropDown_SpectrumType = uidropdown(app.Panel_6);
            app.DropDown_SpectrumType.Items = {'幅度谱', '功率谱'};
            app.DropDown_SpectrumType.Value = '幅度谱';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = xinhao

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end